classdef INTERFAZMAX30102_App_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        uipanel1                matlab.ui.container.Panel
        ContadorEditField       matlab.ui.control.EditField
        ContadorEditFieldLabel  matlab.ui.control.Label
        pushbutton3             matlab.ui.control.Button
        pushbutton2             matlab.ui.control.Button
        text1_slider1           matlab.ui.control.Label
        slider1                 matlab.ui.control.Slider
        text4                   matlab.ui.control.Label
        pushbutton1             matlab.ui.control.Button
        text3                   matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        GrficaTab               matlab.ui.container.Tab
        uipanel2                matlab.ui.container.Panel
        Panel                   matlab.ui.container.Panel
        Sa02EditField           matlab.ui.control.EditField
        Sa02EditFieldLabel      matlab.ui.control.Label
        BPMEditField            matlab.ui.control.EditField
        BPMEditFieldLabel       matlab.ui.control.Label
        SealSwitch              matlab.ui.control.Switch
        SealSwitchLabel         matlab.ui.control.Label
        pushbutton4             matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        ResultadosTab           matlab.ui.container.Tab
        TextResultados          matlab.ui.control.TextArea
        Menu_Archivo            matlab.ui.container.Menu
        AbrirMenu               matlab.ui.container.Menu
        Submenu_Guardar         matlab.ui.container.Menu
        Menu_Inicio             matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        cellArrayText% Cell array of character vectors, private or public attributes according to your needs
    end

    methods (Access = private)
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool4, app.uitoggletool5, app.uitoggletool6];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function INTERFAZMAX30102_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to INTERFAZMAX30102 (see VARARGIN)


            % Choose default command line output for INTERFAZMAX30102
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            %             clear all;
            tiempo = 20;
            assignin('base','tiempo',tiempo);


            fid = fopen('acceso_registro.txt','w');
            fprintf(fid,'%s',int2str(0)); %Se imprime el primer comentario
            fclose(fid);

            %              app.cellArrayText{1} = sprintf('%%CICLO Nº%d:\n',1);
            %              app.cellArrayText{2} = sprintf('pos_max_R(%d)=%d;\n',1,1);
            %              app.TextResultados.Value=app.cellArrayText;% Initial display information in the text area
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.figure1.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {506, 506};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {314, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: Submenu_Guardar
        function Submenu_Guardar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to Submenu_Guardar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Rp = evalin('base','Rp');
            IRp = evalin('base','IRp');
            % filter = {'*.mat'};
            save('Rojopromedio.mat','IRp','Rp');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tiempo = evalin('base','tiempo');
            % f = warndlg(int2str(tiempo2),'Warning');
            handles.axes1.Toolbar.Visible = 'on';
            [IRp,Rp]=Iniciar(tiempo, handles.axes1, handles.Contador,handles.BPM,handles.SaO2, handles.TextResultados, app.cellArrayText, app);
            if (Rp~=0)
                assignin('base','IRp',IRp);
                assignin('base','Rp',Rp);

                t = tiledlayout(app.Panel, 2, 1);
                set(t,'tag','t') %where t is the handle of the slider

                title(t,'Señal Original')
                xlabel(t,'Muestras')
                ylabel(t,'Intensidad')

                %Señal Roja
                ax1 = nexttile(t);
                plot(ax1,Rp,'r')
                title('Señal Roja')
                
                %Señal Infrarroja
                ax2 = nexttile(t);
                plot(ax2,IRp,'b')
                title('Señal Infrarroja')


            end
            clear Rp; clear IRp;


        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = 1;
            fid=fopen('acceso_registro.txt','w');
            fprintf(fid,'%s',int2str(a)); %Se imprime el primer comentario
            fclose(fid);
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            a = 2;
            fid=fopen('acceso_registro.txt','w');
            fprintf(fid,'%s',int2str(a)); %Se imprime el primer comentario
            fclose(fid);
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            t=findall(0,'tag', 't');
            delete(t);
            t2=findall(0,'tag', 't2');
            delete(t2);
            clear Rp; clear IRp;
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            tiempo=round(90*get(handles.slider1,'Value')+1); % returns position of slider
            set(handles.text1_slider1, 'String', tiempo);
            assignin('base','tiempo',tiempo);
        end

        % Callback function
        function uitoggletool6_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool6.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool5_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool5.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            pan(app.figure1, char(state));
        end

        % Value changed function: SealSwitch
        function SealSwitchValueChanged(app, event)
            value = app.SealSwitch.Value;
            switch value

                case 'Original'
                    app.Panel.Visible = 'on';
                    app.Panel_2.Visible = 'off';

                case 'Procesada'
                    app.Panel.Visible = 'off';
                    app.Panel_2.Visible = 'on';


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'INTERFAZMAX30102';
            app.figure1.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.figure1.Tag = 'figure1';
            app.GridLayout = uigridlayout(app.figure1);
            app.LeftPanel = uipanel(app.GridLayout);
            app.uipanel1 = uipanel(app.LeftPanel);
            app.uipanel1.Title = 'Panel';
            app.uipanel1.Tag = 'uipanel1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Inicio';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Iniciar';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tiempo de la muestra (segundos):';
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.text1_slider1 = uilabel(app.uipanel1);
            app.text1_slider1.Tag = 'text1_slider1';
            app.text1_slider1.Text = '30';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Registrar';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Detener';
            app.ContadorEditFieldLabel = uilabel(app.uipanel1);
            app.ContadorEditFieldLabel.Text = 'Contador';
            app.ContadorEditField = uieditfield(app.uipanel1, 'text');
            app.ContadorEditField.Tag = 'Contador';
            app.ContadorEditField.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GrficaTab = uitab(app.TabGroup);
            app.GrficaTab.Title = 'Gráfica';
            app.uipanel2 = uipanel(app.GrficaTab);
            app.uipanel2.Tag = 'uipanel2';
            app.Panel_2 = uipanel(app.uipanel2);
            app.pushbutton4 = uibutton(app.uipanel2, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Limpiar';
            app.SealSwitchLabel = uilabel(app.uipanel2);
            app.SealSwitchLabel.Text = 'Señal';
            app.SealSwitch = uiswitch(app.uipanel2, 'slider');
            app.SealSwitch.Items = {'Original', 'Procesada'};
            app.SealSwitch.ValueChangedFcn = createCallbackFcn(app, @SealSwitchValueChanged, true);
            app.SealSwitch.Value = 'Original';
            app.BPMEditFieldLabel = uilabel(app.uipanel2);
            app.BPMEditFieldLabel.Text = 'BPM';
            app.BPMEditField = uieditfield(app.uipanel2, 'text');
            app.BPMEditField.Tag = 'BPM';
            app.BPMEditField.Editable = 'off';
            app.Sa02EditFieldLabel = uilabel(app.uipanel2);
            app.Sa02EditFieldLabel.Text = '%Sa02';
            app.Sa02EditField = uieditfield(app.uipanel2, 'text');
            app.Sa02EditField.Tag = 'SaO2';
            app.Sa02EditField.Editable = 'off';
            app.Panel = uipanel(app.uipanel2);
            app.Panel.Tag = 'Graficas';
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.TextResultados = uitextarea(app.ResultadosTab);
            app.TextResultados.Tag = 'TextResultados';
            app.TextResultados.Editable = 'off';
            app.Menu_Archivo = uimenu(app.figure1);
            app.Menu_Archivo.Text = 'Archivo';
            app.Menu_Archivo.Tag = 'Menu_Archivo';
            app.AbrirMenu = uimenu(app.Menu_Archivo);
            app.AbrirMenu.Text = 'Abrir...';
            app.AbrirMenu.Tag = 'Abrir';
            app.Submenu_Guardar = uimenu(app.Menu_Archivo);
            app.Submenu_Guardar.MenuSelectedFcn = createCallbackFcn(app, @Submenu_Guardar_Callback, true);
            app.Submenu_Guardar.Text = 'Guardar';
            app.Submenu_Guardar.Tag = 'Submenu_Guardar';
            app.Menu_Inicio = uimenu(app.figure1);
            app.Menu_Inicio.Text = 'Inicio';
            app.Menu_Inicio.Tag = 'Menu_Inicio';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTERFAZMAX30102_App_autoreflow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)INTERFAZMAX30102_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end